classdef DobotTeach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        JointDegCartesianmmLabel  matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        ZSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        ZSlider                   matlab.ui.control.Slider
        JOINT1Knob                matlab.ui.control.Knob
        JOINT2Knob                matlab.ui.control.Knob
        JOINT3Knob                matlab.ui.control.Knob
        JOINT4Knob                matlab.ui.control.Knob
        JOINT5Knob                matlab.ui.control.Knob
        JOINT5KnobLabel           matlab.ui.control.Label
        JOINT4KnobLabel           matlab.ui.control.Label
        JOINT3KnobLabel           matlab.ui.control.Label
        JOINT2KnobLabel           matlab.ui.control.Label
        JOINT1KnobLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        robot = DobotMagicianwithGripper % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanging(app, event)

        end

        % Value changed function: JOINT1Knob
        function JOINT1KnobValueChanging(app, event)
            q1_target = pi/180* event.Value;
            steps = 10;
            joint_current = app.robot.model.getpos;
            
            q_trajectory = repmat(joint_current,steps,1);
            q1 = lspb(joint_current(1),q1_target,steps);
            q_trajectory(:,1) = q1; 

            app.robot.MoveRobot(q_trajectory);
        end

        % Value changed function: JOINT2Knob
        function JOINT2KnobValueChanging(app, event)
            q2_target = pi/180* event.Value;
            steps = 10;
            joint_current = app.robot.model.getpos;
            
            q_trajectory = repmat(joint_current,steps,1);
            q2 = lspb(joint_current(2),q2_target,steps);
            q_trajectory(:,2) = q2; 

            app.robot.MoveRobot(q_trajectory);
        end

        % Value changed function: JOINT3Knob
        function JOINT3KnobValueChanging(app, event)
            q3_target = pi/180* event.Value;
            steps = 10;
            joint_current = app.robot.model.getpos;
            
            q_trajectory = repmat(joint_current,steps,1);
            q3 = lspb(joint_current(3),q3_target,steps);
            q_trajectory(:,3) = q3; 

            app.robot.MoveRobot(q_trajectory);        
        end

        % Value changed function: JOINT4Knob
        function JOINT4KnobValueChanging(app, event)
            q4_target = pi/180* event.Value;
            steps = 10;
            joint_current = app.robot.model.getpos;
            
            q_trajectory = repmat(joint_current,steps,1);
            q4 = lspb(joint_current(4),q4_target,steps);
            q_trajectory(:,4) = q4; 

            app.robot.MoveRobot(q_trajectory);        
        end

        % Value changed function: JOINT5Knob
        function JOINT5KnobValueChanging(app, event)
            q5_target = pi/180* event.Value;
            steps = 10;
            joint_current = app.robot.model.getpos;
            
            q_trajectory = repmat(joint_current,steps,1);
            q5 = lspb(joint_current(5),q5_target,steps);
            q_trajectory(:,5) = q5; 

            app.robot.MoveRobot(q_trajectory);               
        end

        % Value changed function: XSlider
        function XSliderValueChanging(app, event)
            x_move = event.Value/1000;
            pose_current = app.robot.model.fkine(app.robot.model.getpos);
            pose_target = transl(x_move-pose_current(1,4),0,0)*pose_current;
            RMRCMotion(app.robot,pose_target,30);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanging(app, event)
            z_move = event.Value/1000;
            pose_current = app.robot.model.fkine(app.robot.model.getpos);
            pose_target = transl(0,0,z_move-pose_current(3,4))*pose_current;
            RMRCMotion(app.robot,pose_target,30);      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JOINT1KnobLabel = uilabel(app.UIFigure);
            app.JOINT1KnobLabel.Text = 'JOINT 1';
            app.JOINT2KnobLabel = uilabel(app.UIFigure);
            app.JOINT2KnobLabel.Text = 'JOINT 2';
            app.JOINT3KnobLabel = uilabel(app.UIFigure);
            app.JOINT3KnobLabel.Text = 'JOINT 3';
            app.JOINT4KnobLabel = uilabel(app.UIFigure);
            app.JOINT4KnobLabel.Text = 'JOINT 4';
            app.JOINT5KnobLabel = uilabel(app.UIFigure);
            app.JOINT5KnobLabel.Text = 'JOINT 5';
            app.JOINT5Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT5Knob.Limits = [-90 90];
            app.JOINT5Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT5KnobValueChanging, true);
            app.JOINT4Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT4Knob.Limits = [-140 140];
            app.JOINT4Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT4KnobValueChanging, true);
            app.JOINT3Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT3Knob.Limits = [-15 90];
            app.JOINT3Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT3KnobValueChanging, true);
            app.JOINT2Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT2Knob.Limits = [-5 90];
            app.JOINT2Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT2KnobValueChanging, true);
            app.JOINT1Knob = uiknob(app.UIFigure, 'continuous');
            app.JOINT1Knob.Limits = [-120 120];
            app.JOINT1Knob.ValueChangedFcn = createCallbackFcn(app, @JOINT1KnobValueChanging, true);
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [150 210];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.Value = 150;
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-150 150];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [170 320];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.Value = 170;
            app.JointDegCartesianmmLabel = uilabel(app.UIFigure);
            app.JointDegCartesianmmLabel.Text = 'Joint (Deg)  Cartesian (mm)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotTeach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end